classdef CSMAwithCA_1Tests < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        UIAxes                       matlab.ui.control.UIAxes
        StartSimulationButton        matlab.ui.control.Button
        NumberofNodesEditFieldLabel  matlab.ui.control.Label
        NumberofNodesEditField       matlab.ui.control.NumericEditField
        CreateNodesButton            matlab.ui.control.Button
        ExecFunctionCommandWindowButton  matlab.ui.control.Button
    end

    
    % Global variables, call them by prepending 'app.' in front of them
    properties (Access = private)
        NodesLocationArray % Array which contains the coordinates of each node
        SimulationSpeed = 1 % Speed at which the signal moves in terms of seconds [x]
        
    end
    
    methods (Access = private)
        
        % This is a custom function that you can use
        function customFunction(app, par1)
            disp(['The simulation speed is set at: ', num2str(app.SimulationSpeed), ' seconds per frame'])
            
            % Variables are not stored in the workspace by default (can use debug mode to show it)
            % Or use the function assignin (should declare it locally first before assignin works)            
            if (par1 == true)
                simspeed = app.SimulationSpeed;            
                assignin('base', 'savedVar_simspeed', simspeed);
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Input stuff that you want to execute at startup
            disp('App has started up!') % Will display on the main MATLAB command window    
            xlim(app.UIAxes, [0 1]);
            ylim(app.UIAxes, [0 1]);
        end

        % Button pushed function: CreateNodesButton
        function CreateNodesButtonPushed(app, event)
            cla(app.UIAxes) % Clears the whole axes when new nodes are created
            N = app.NumberofNodesEditField.Value; % Number of nodes, input from user
            
            app.NodesLocationArray = rand(N,2); % Creates random N x 2 array [2: (X,Y) values]
            
            % Plot nodes as small circles
            plot(app.UIAxes, app.NodesLocationArray(:,1), app.NodesLocationArray(:,2), 'ok', 'MarkerfaceColor', 'k');
        end

        % Button pushed function: StartSimulationButton
        function StartSimulationButtonPushed(app, event)
            theta = linspace(0,2*pi,100);
            radius = 0.05; % Hop for each iteration [x]
            
            hold(app.UIAxes, 'on') % Ensures all previous plots (nodes) will remain on the axes

            time = 100;                                               % Simulation time
            slots = 10;                                               % Divide 1 time unit into 10 slots
            startAt=slots+1;
            nodes = app.NumberofNodesEditField.Value;                 % x and y value of all the nodes
            sendArray = zeros([nodes, startAt + time*slots]); 
            senseArray = zeros([nodes, startAt + time*slots]);        % Nxtime array that equal 1 when a node wants to transmit and 0 otherwise
            QueueNode = zeros([nodes, 1]);                            % Nx1 array that contains the number of messages to be send by node i
            retransmission = {};                                      % Variable array that contains a counter(coupled with a node id) when a packet needs to be resend  
            transmission = {};
            nmbrRetransmission=zeros([nodes, 1]);
            load=1;
            % all nodes send on average 1 packet per second.
            nmbrPacketsToBeSent=load * time;
            for i=1:nmbrPacketsToBeSent
                rndNode = randi(nodes);
                rndSlot = randi(time*slots);
                transmission{size(transmission, 1) + 1, 1} = rndSlot;
                transmission{size(transmission, 1), 2} = rndNode;
            end
            nmbrPacketsSucces=0;
            % Main loop over all the time slots.
            for i =startAt:(time*slots+startAt)
                for j=1:nodes                  
                    % If there is a packet in the queue for node j then it
                    % should sense the channel.
                    if QueueNode(j) > 0
                        senseArray(j, i) = 1;
                    end
                end
                % Check whether the channel is idle
                if sum(sendArray(:, i-1:i),"all")==0
                    nmbrSenders = 0;
                    for node=1:nodes
                        % Each node with a packet in the queue wants to
                        % send.
                        if QueueNode(node)>0
                            nmbrSenders = nmbrSenders + 1;
                        end
                    end
                    for node=1:nodes
                       % check whether we are dealing with a collision. If
                       % this is the case they should only send for 1
                       % subslot since we want to implement Collision
                       % avoidance.
                       if nmbrSenders >= 2
                           if QueueNode(node)>0
                                sendArray(node, i) = 1;
                                QueueNode(node) = QueueNode(node)-1;
                           end
                       % if this hold a node is free to send it whole
                       % packet.
                       elseif QueueNode(node)>0
                                nmbrPacketsSucces = nmbrPacketsSucces +1;
                                sendArray(node, i:i+slots) = 1;
                                QueueNode(node) = QueueNode(node)-1;           
                       end
                    end
                end
                % Now we have a collison
                if sum(sendArray(:, i),"all")>1
                    for node=1:nodes
                        if sendArray(node, i)==1
                            % adding the packet to the retransmission array
                            % with a random delay.
                            %slotsDelay = randi(pow2(3+nmbrRetransmission(node)))*slots;
                            slotsDelay = randi(10)*slots;
                            retransmission{size(retransmission, 1) + 1, 1} = slotsDelay;
                            retransmission{size(retransmission, 1), 2} = node;
                            if nmbrRetransmission(node) < 40
                                nmbrRetransmission(node) = nmbrRetransmission(node) + 1;
                            end
                        end
                    end
                end
                for rt=size(transmission, 1):-1:1
                    % Decrement the time counter
                    transmission{rt, 1} = transmission{rt,1}-1;
                    % if the counter equals 0 then we should resend and
                    % thus add it to the queue of the corresponding node.
                    if transmission{rt, 1}==0
                        QueueNode(transmission{rt, 2}) = QueueNode(transmission{rt, 2}) + 1;
                        transmission(rt, :) = [];
                    end
               end
                % Go over all the retransmissions
                for rt=size(retransmission, 1):-1:1
                    % Decrement the time counter
                    retransmission{rt, 1} = retransmission{rt,1}-1;
                    % if the counter equals 0 then we should resend and
                    % thus add it to the queue of the corresponding node.
                    if retransmission{rt, 1}==0
                        QueueNode(retransmission{rt, 2}) = QueueNode(retransmission{rt, 2}) + 1;
                        retransmission(rt, :) = [];
                    end
                end
            end
            if nmbrPacketsToBeSent ~= 0
                nmbrPacketsSucces/nmbrPacketsToBeSent
            end
       
        
          % Simulation.
          for s = 1:time*slots+startAt
               nbrTrans = 0;
               nbrSense = 0;
               % Plot the sending and sensing
               for node = 1:nodes
                   if sendArray(node, s) == 1
                       nbrTrans = nbrTrans + 1;
                       for i = 1:20                
                           plot(app.UIAxes, app.NodesLocationArray(node,1)+radius*i*cos(theta),app.NodesLocationArray(node,2)+radius*i*sin(theta), '-k');
                       end
                   elseif senseArray(node, s) == 1
                       nbrSense = nbrSense + 1;
                       for i = 1:5                
                           plot(app.UIAxes, app.NodesLocationArray(node,1)+radius*i*cos(theta),app.NodesLocationArray(node,2)+radius*i*sin(theta));
                       end                        
                   end
               end
               pause(1/slots)
               % remove the sense and sending visuals
               children = get(app.UIAxes, 'children');
               if nbrTrans+nbrSense>0
                   delete(children(1:nbrTrans*20+nbrSense*5));
               end
          end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, {'MAC protocol '; 'simulation'})
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.StartSimulationButton = uibutton(app.UIFigure, 'push');
            app.StartSimulationButton.ButtonPushedFcn = createCallbackFcn(app, @StartSimulationButtonPushed, true);
            app.StartSimulationButton.Text = 'Start Simulation';
            app.NumberofNodesEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofNodesEditFieldLabel.Text = 'Number of Nodes';
            app.NumberofNodesEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumberofNodesEditField.Value = 10;
            app.CreateNodesButton = uibutton(app.UIFigure, 'push');
            app.CreateNodesButton.ButtonPushedFcn = createCallbackFcn(app, @CreateNodesButtonPushed, true);
            app.CreateNodesButton.Text = 'Create Nodes';
            app.ExecFunctionCommandWindowButton = uibutton(app.UIFigure, 'push');
            app.ExecFunctionCommandWindowButton.Text = {'Exec Function'; 'Command'; 'Window'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CSMAwithCA_1Tests

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end