classdef Dynamic_Source_Routing < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        UIAxes                       matlab.ui.control.UIAxes
        StartSimulationButton        matlab.ui.control.Button
        NumberofNodesEditFieldLabel  matlab.ui.control.Label
        NumberofNodesEditField       matlab.ui.control.NumericEditField
        CreateNodesButton            matlab.ui.control.Button
        ExecFunctionCommandWindowButton  matlab.ui.control.Button
        RangeSliderLabel             matlab.ui.control.Label
        RangeSlider                  matlab.ui.control.Slider
        StopSimulationButton         matlab.ui.control.Button
    end

    
    % Global variables, call them by prepending 'app.' in front of them
    properties (Access = private)
        NodesLocationArray % Description
        SimulationSpeed = 0.05 % Speed at which the signal moves in terms of seconds
        nodeRadius = 0.1; %The transmit radius that every node has
        nodeAmount; %The amount of nodes that are plotted
        A; %The adjecency matrix that is used for the different connections  
        simulationStatus = false;
    end
    
    methods (Access = private)
        
        % This is a custom function that you can use
        function customFunction(app, par1)
            disp(['The simulation speed is set at: ', num2str(app.SimulationSpeed), ' seconds per frame'])
            
            % Variables are not stored in the workspace by default (can use debug mode to show it)
            % Or use the function assignin (should declare it locally first before assignin works)            
            if (par1 == true)
                simspeed = app.SimulationSpeed;            
                assignin('base', 'savedVar_simspeed', simspeed);
            end
        end
        
        %function that is used to check if the nodes are in eachothers vicinity, if they are a connection should be established  
        function checkNodeConnection(app)
            %every node is compared to all the other nodes therefore double for loop
            for i = 1:app.nodeAmount
                xCoordinate = app.NodesLocationArray(i,1);
                yCoordinate = app.NodesLocationArray(i,2);
                
                for z = 1:app.nodeAmount
                    if (z == i)
                        %do nothing if its the same node
                    else
                        x = xCoordinate - app.NodesLocationArray(z, 1);
                        y = yCoordinate - app.NodesLocationArray(z, 2);               
                        distance = sqrt((x * x) + (y * y));                     
                        r = 2 * app.nodeRadius;
                        %disp(r)
                        %disp(distance)
                        
                        %If the distance between the two nodes smaller is than the distance of the two radii, then they are in eachothers range
                        if(distance <= r)
                            app.A(i,z) = 1;
                            app.A(z,i) = 1;
                            line(app.UIAxes, [app.NodesLocationArray(i,1) app.NodesLocationArray(z,1)], [app.NodesLocationArray(i,2) app.NodesLocationArray(z,2)], 'Color', 'k');  
                        end
                    end
                end            
            end          
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Input stuff that you want to execute at startup
            disp('App has started up!') % Will display on the main MATLAB command window
            xlim(app.UIAxes, [0 1]);
            ylim(app.UIAxes, [0 1]);
        end

        % Button pushed function: CreateNodesButton
        function CreateNodesButtonPushed(app, event)
            theta = linspace(0,2*pi,100);
            
            cla(app.UIAxes) % Clears the whole axes when new nodes are created
            app.nodeAmount = app.NumberofNodesEditField.Value; % Number of nodes, input from user
            
            app.NodesLocationArray = rand(app.nodeAmount,2); % Creates random N x 2 array [2: (X,Y) values]
            
            % Plot nodes as small circles
            plot(app.UIAxes, app.NodesLocationArray(:,1), app.NodesLocationArray(:,2), 'ok', 'MarkerfaceColor', 'k');
            hold(app.UIAxes, 'on');
            
            %Plot cirkels around the nodes that serve as noderange, they got a red color
            for i = 1:app.nodeAmount
                    plot(app.UIAxes, app.NodesLocationArray(i,1)+app.nodeRadius*cos(theta),app.NodesLocationArray(i,2)+app.nodeRadius*sin(theta), '-b'); 
            end
            
        end

        % Button pushed function: StartSimulationButton
        function StartSimulationButtonPushed(app, event)
            app.simulationStatus = true;
                 
                hold(app.UIAxes, 'on') % Ensures all previous plots (nodes) will remain on the axes
                
                nmbrNodes=size(app.NodesLocationArray, 1);                     % N x 1 array
                app.A=zeros([nmbrNodes, nmbrNodes]);                           %Adjecency matrix of all connected nodes
                
                
                %linkProbability = 0.2;                                          %probablitiy of links between nodes?
                %cache = {};                                                     probably not needed      
                
                %for loop to fill in the A array if random is under the certain linkprobability for that specific element 1 and 1 are filled 
    %              for i = 1:nmbrNodes
    %                  for j = (i+1):nmbrNodes
    %                      rndNmbr = rand(1);
    %                      if rndNmbr < linkProbability
    %                          app.A(i,j) = 1;
    %                          app.A(j,i) = 1;
    %                          line(app.UIAxes, [app.NodesLocationArray(i,1) app.NodesLocationArray(j,1)], [app.NodesLocationArray(i,2) app.NodesLocationArray(j,2)], 'Color', 'k'); 
    %                      end
    %                  end                
    %              end
    
                
                checkNodeConnection(app);                                       %function to check if a connection should be made between the nodes (distance and radii between nodes are used)
                %disp(app.A);
                
                %two nodes are randomly chosen to send messages with eachother
                srcNode=1;
                text(app.UIAxes, app.NodesLocationArray(srcNode,1), app.NodesLocationArray(srcNode,2), "Source")
                dstNode=randi([2, nmbrNodes]);
                text(app.UIAxes, app.NodesLocationArray(dstNode,1), app.NodesLocationArray(dstNode,2), "Destination")
                
                alreadyFlood = zeros([nmbrNodes,1]);
                alreadyFlood(srcNode)=1;
                flood = zeros([nmbrNodes,1]);
                flood(srcNode) = 1;
                floodNext = zeros([nmbrNodes,1]);
                dstNodeReached=0;
                
                cache = {};
                cache{srcNode,1} = srcNode;
                hopnumber = 0;
                
                while (sum(flood)>0)
                    hopnumber=hopnumber+1
                    nmbrFloods=0;   
                    for i=1:nmbrNodes
                        if (flood(i) == 1)
                            for j=1:nmbrNodes
                                if app.A(i,j)==1
                                   nmbrFloods = nmbrFloods + 1;
                                   quiver(app.UIAxes, app.NodesLocationArray(i,1), app.NodesLocationArray(i,2), app.NodesLocationArray(j,1)-app.NodesLocationArray(i,1), app.NodesLocationArray(j,2)-app.NodesLocationArray(i,2), 'Color', 'r');                                 
                                   if j==dstNode && dstNodeReached==0                                                                  
                                        dstNodeReached=1;
                                        for ix=1:size(cache(i,:),2)
                                            cache(j,ix)=cache(i, ix);
                                        end    
                                        cache{j,hopnumber+1}=j;
                                   elseif alreadyFlood(j)==0 && j~=dstNode
                                        floodNext(j)=1;  
                                        for ix=1:size(cache(i,:),2)
                                            cache(j,ix)=cache(i, ix);
                                        end    
                                        cache{j,hopnumber+1}=j;
                                   end    
                
                                end                           
                            end
                            alreadyFlood(i)=1;
                            flood(i)=0;
                        end
                    end
                    
                   pause(2)
                   children = get(app.UIAxes, 'children');
                   if nmbrFloods>0                    
                         delete(children(1:nmbrFloods));
                   end
                   
                   for idx=1:nmbrNodes
                       disp(idx)
                        if floodNext(idx)==1
                            flood(idx)=1;
                            floodNext(idx)=0;
                            alreadyFlood(idx)=1;
                        end
                   end                  
                end 
                
                %nmbrNodes
                if dstNodeReached==1             
                    for i=size(cache(dstNode,:),2):-1:2
                        currentNode = cache{dstNode, i};
                        nextNode = cache{dstNode, i-1};
                        quiver(app.UIAxes, app.NodesLocationArray(currentNode,1), app.NodesLocationArray(currentNode,2), app.NodesLocationArray(nextNode,1)-app.NodesLocationArray(currentNode,1), app.NodesLocationArray(nextNode,2)-app.NodesLocationArray(currentNode,2), 'Color', 'r')
                        pause(1)
                    end
                end
            
        end

        % Button pushed function: ExecFunctionCommandWindowButton
        function ExecFunctionCommandWindowButtonPushed(app, event)
            customFunction(app, true); % Executes our custom function
        end

        % Value changed function: RangeSlider
        function RangeSliderValueChanged(app, event)
            value = app.RangeSlider.Value;
            app.nodeRadius = value/100;
        end

        % Button pushed function: StopSimulationButton
        function StopSimulationButtonPushed(app, event)
            cla(app.UIAxes) % Clears the whole axes whensimulation should stop
            app.simulationStatus = false;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, {'Routing protocol '; 'simulation'})
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.StartSimulationButton = uibutton(app.UIFigure, 'push');
            app.StartSimulationButton.ButtonPushedFcn = createCallbackFcn(app, @StartSimulationButtonPushed, true);
            app.StartSimulationButton.Text = 'Start Simulation';
            app.NumberofNodesEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofNodesEditFieldLabel.Text = 'Number of Nodes';
            app.NumberofNodesEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumberofNodesEditField.Value = 10;
            app.CreateNodesButton = uibutton(app.UIFigure, 'push');
            app.CreateNodesButton.ButtonPushedFcn = createCallbackFcn(app, @CreateNodesButtonPushed, true);
            app.CreateNodesButton.Text = 'Create Nodes';
            app.ExecFunctionCommandWindowButton = uibutton(app.UIFigure, 'push');
            app.ExecFunctionCommandWindowButton.ButtonPushedFcn = createCallbackFcn(app, @ExecFunctionCommandWindowButtonPushed, true);
            app.ExecFunctionCommandWindowButton.Text = {'Exec Function'; 'Command'; 'Window'};
            app.RangeSliderLabel = uilabel(app.UIFigure);
            app.RangeSliderLabel.Text = 'Range';
            app.RangeSlider = uislider(app.UIFigure);
            app.RangeSlider.ValueChangedFcn = createCallbackFcn(app, @RangeSliderValueChanged, true);
            app.RangeSlider.Value = 10;
            app.StopSimulationButton = uibutton(app.UIFigure, 'push');
            app.StopSimulationButton.ButtonPushedFcn = createCallbackFcn(app, @StopSimulationButtonPushed, true);
            app.StopSimulationButton.Text = 'Stop Simulation';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Dynamic_Source_Routing

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end