classdef Dynamic_Source_Routing_5 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DSRSimulationUIFigure          matlab.ui.Figure
        StartSimulationButton          matlab.ui.control.Button
        NumberofNodesEditFieldLabel    matlab.ui.control.Label
        NumberofNodesEditField         matlab.ui.control.NumericEditField
        CreateNodesButton              matlab.ui.control.Button
        AddNodesButton                 matlab.ui.control.Button
        RangeSliderLabel               matlab.ui.control.Label
        RangeSlider                    matlab.ui.control.Slider
        ClearSimulationButton          matlab.ui.control.Button
        SimulationstatusIdleLabel      matlab.ui.control.Label
        NewmessageButton               matlab.ui.control.Button
        SourceNodeEditFieldLabel       matlab.ui.control.Label
        SourceNodeEditField            matlab.ui.control.NumericEditField
        DestinationNodeEditFieldLabel  matlab.ui.control.Label
        DestinationNodeEditField       matlab.ui.control.NumericEditField
        Time0Label                     matlab.ui.control.Label
        MovementSliderLabel            matlab.ui.control.Label
        MovementSlider                 matlab.ui.control.Slider
        PauseButton                    matlab.ui.control.Button
        PlayButton                     matlab.ui.control.Button
        XEditFieldLabel                matlab.ui.control.Label
        XEditField                     matlab.ui.control.NumericEditField
        YEditFieldLabel                matlab.ui.control.Label
        YEditField                     matlab.ui.control.NumericEditField
        AddNodeButton                  matlab.ui.control.Button
        NodePlacementTextAreaLabel     matlab.ui.control.Label
        NodePlacementTextArea          matlab.ui.control.TextArea
        Nodesadded0Label               matlab.ui.control.Label
        SpeedSliderLabel               matlab.ui.control.Label
        SpeedSlider                    matlab.ui.control.Slider
        RangeField                     matlab.ui.control.NumericEditField
        MovementField                  matlab.ui.control.NumericEditField
        SpeedField                     matlab.ui.control.NumericEditField
        Connectionbroken0Label         matlab.ui.control.Label
        UIAxes                         matlab.ui.control.UIAxes
    end

    
    % Global variables, call them by prepending 'app.' in front of them
    properties (Access = private)
        NodesLocationArray = {[],[]} % Description
        SimulationSpeed = 0.05 % Speed at which the signal moves in terms of seconds
        nodeRadius = 0.4; %The transmit radius that every node has
        nodeAmount = 0; %The amount of nodes that are plotted
        A; %The adjecency matrix that is used for the different connections  
        simulationStatus = false;
        time = 0; %Time in the simulation used for checking how long to send a message
        movement = 100;
        pauseStatus = false;
        speed = 1;
        brokenCounter = 0;
    end
    
    methods (Access = private)
        
        % This is a custom function that you can use
        function customFunction(app, par1)
            disp(['The simulation speed is set at: ', num2str(app.SimulationSpeed), ' seconds per frame'])
            
            % Variables are not stored in the workspace by default (can use debug mode to show it)
            % Or use the function assignin (should declare it locally first before assignin works)            
            if (par1 == true)
                simspeed = app.SimulationSpeed;            
                assignin('base', 'savedVar_simspeed', simspeed);
            end
        end
        
        %function that is used to check if the nodes are in eachothers vicinity, if they are a connection should be established  
        function checkNodeConnection(app)
            %The adjacency matrix needs to be reset on every connection
            nmbrNodes=size(app.NodesLocationArray, 1);
            app.A=zeros([nmbrNodes, nmbrNodes]);  %Adjecency matrix of all connected nodes
            
            %every node is compared to all the other nodes therefore double for loop
            for i = 1:app.nodeAmount
                xCoordinate = app.NodesLocationArray(i,1);
                yCoordinate = app.NodesLocationArray(i,2);
                
                for z = 1:app.nodeAmount
                    if (z == i)
                        %do nothing if its the same node
                    else
                        x = xCoordinate - app.NodesLocationArray(z, 1);
                        y = yCoordinate - app.NodesLocationArray(z, 2);               
                        distance = sqrt((x * x) + (y * y));                     
                        r = 2 * app.nodeRadius;
   
                        %If the distance between the two nodes smaller is than the distance of the two radii, then they are in eachothers range
                        if(distance <= r)
                            app.A(i,z) = 1;
                            app.A(z,i) = 1;
                            line(app.UIAxes, [app.NodesLocationArray(i,1) app.NodesLocationArray(z,1)], [app.NodesLocationArray(i,2) app.NodesLocationArray(z,2)], 'Color', 'k');  %make the black connection line between the nodes
                            disp(z)
                        end
                    end
                end            
            end
            disp(app.A)
        end
        
        %function to start the whole protocol to first flood the network to search the destination node, when found data will be transmitted to the source node
        function StartProtocol(app)          
            hold(app.UIAxes, 'on') % Ensures all previous plots (nodes) will remain on the axes
            nmbrNodes=size(app.NodesLocationArray, 1);                     % N x 1 array
            
            %two nodes are randomly chosen to send messages with eachother
            srcNode=app.SourceNodeEditField.Value;
            text(app.UIAxes, app.NodesLocationArray(srcNode,1) - 0.04, app.NodesLocationArray(srcNode,2) - 0.02, "Source",'FontSize', 14) %Added a bit offset of -0.04 and -0.02
            dstNode=app.DestinationNodeEditField.Value;
            text(app.UIAxes, app.NodesLocationArray(dstNode,1) - 0.06, app.NodesLocationArray(dstNode,2) - 0.02, "Destination",'FontSize', 14) %Added a bit offset of -0.06 and -0.02
            
            %cache = {};
            %cache{srcNode,1} = srcNode;
            %hopnumber = 0;
            cnctinLost = 1;
            
            %label for setting up the status of the simulation
            app.SimulationstatusIdleLabel.Text = 'Simulation status: Searching destination';
            drawnow
            %added a while loop that is used if the connection was lost during data sending, flooding ahs to occure again
            while(cnctinLost > 0)
                cnctinLost = cnctinLost - 1;
                cache = {};
                cache{srcNode,1} = srcNode;
                hopnumber = 0;
                alreadyFlood = zeros([nmbrNodes,1]);
                alreadyFlood(srcNode)=1;
                flood = zeros([nmbrNodes,1]);
                flood(srcNode) = 1;
                floodNext = zeros([nmbrNodes,1]);
                dstNodeReached=0;
                
                while (sum(flood)>0)
                    hopnumber=hopnumber+1;
                    nmbrFloods=0;   
                    for i=1:nmbrNodes
                        if (flood(i) == 1)
                            for j=1:nmbrNodes
                                if app.A(i,j)==1
                                   nmbrFloods = nmbrFloods + 1;                                      
                                   quiver(app.UIAxes, app.NodesLocationArray(i,1), app.NodesLocationArray(i,2), app.NodesLocationArray(j,1)-app.NodesLocationArray(i,1), app.NodesLocationArray(j,2)-app.NodesLocationArray(i,2), 'Color', 'r');                                     
                                   %moveNodes(app,i,j,0,srcNode,dstNode);
                                   %timeDelay(app,1);
                                   if j==dstNode && dstNodeReached==0                                                                  
                                        dstNodeReached=1;
                                        for ix=1:size(cache(i,:),2)
                                            cache(j,ix)=cache(i, ix);
                                        end    
                                        cache{j,hopnumber+1}=j;
                                   elseif alreadyFlood(j)==0 && j~=dstNode
                                        floodNext(j)=1;  
                                        for ix=1:size(cache(i,:),2)
                                            cache(j,ix)=cache(i, ix);
                                        end    
                                        cache{j,hopnumber+1}=j;
                                   end    
                
                                end                           
                            end
                            alreadyFlood(i)=1;
                            flood(i)=0;
                        end
                    end

                   timeDelay(app,2);        %replaced the pause with the time function
                   moveNodes(app,i,j,0,srcNode,dstNode);
                   
                   %commented out dont know if it has a big influence but when used some connections are randomly removed and added again
                   %children = get(app.UIAxes, 'children');
                   %if nmbrFloods>0                    
                   %      delete(children(1:nmbrFloods));
                   %end
                   
                   for idx=1:nmbrNodes
                       %disp(idx)
                        if floodNext(idx)==1
                            flood(idx)=1;
                            floodNext(idx)=0;
                            alreadyFlood(idx)=1;
                        end
                   end                  
                end                        
                
                %For loop for transfering the data from source to destination
                if dstNodeReached==1 
                    app.SimulationstatusIdleLabel.Text = 'Simulation status: Sending RREP';
                    drawnow
                    for i=size(cache(dstNode,:),2):-1:2
                        currentNode = cache{dstNode, i};
                        nextNode = cache{dstNode, i-1};
                        %disp('connection1:')
                        %disp(app.A(currentNode,nextNode))
                        
                        %Need to check if the connection still exists
                        if(app.A(currentNode,nextNode) == 0)
                            app.SimulationstatusIdleLabel.Text = 'Simulation status: Connection is broken!';
                            
                            app.brokenCounter = app.brokenCounter + 1;
                            connectionLabel = sprintf('Connection broken: %d', app.brokenCounter);
                            app.Connectionbroken0Label.Text = connectionLabel;
                            drawnow;

                            timeDelay(app,2);   
                            cnctinLost = 1;     %flag that flooding needs to happen again
                            break;
                        end                       
                        
                        timeDelay(app,2);   %replaced the pause with the time function
                        moveNodes(app, currentNode, nextNode,1,srcNode,dstNode);
                        if(nextNode == srcNode)
                            app.SimulationstatusIdleLabel.Text = 'Simulation status: RREP is received!';
                            drawnow                            
                        end
                    end
                else
                    app.SimulationstatusIdleLabel.Text = 'Simulation status: No connection was established!';
                    drawnow
                end
            end           
        end
        
        %function for drawing the nodes with their radii
        function drawNetwork(app)
            theta = linspace(0,2*pi,100);
            %cla(app.UIAxes) % Clears the whole axes when new nodes are created
            
            % Plot nodes as small circles
            plot(app.UIAxes, app.NodesLocationArray(:,1), app.NodesLocationArray(:,2), 'ok', 'MarkerfaceColor', 'k');
            
            %Give the nodes a number           
            drawNodeNumbers(app);  
            hold(app.UIAxes, 'on');
            
            %Plot cirkels around the nodes that serve as noderange, they got a red color
            for i = 1:app.nodeAmount
                    plot(app.UIAxes, app.NodesLocationArray(i,1)+app.nodeRadius*cos(theta),app.NodesLocationArray(i,2)+app.nodeRadius*sin(theta), '-b'); 
            end            
        end
        
        %Function for giving every node a number
        function drawNodeNumbers(app)
            for i = 1:app.nodeAmount
                nodeNumber = sprintf('%d', i);
                text(app.UIAxes, app.NodesLocationArray(i,1) + 0.02, app.NodesLocationArray(i,2) + 0.02, nodeNumber,'FontSize', 20)
            end
        end
        
        %Function for having some sort of time sense
        function timeDelay(app,delay)
            while(app.pauseStatus)
                pause(1);
            end
            
            for i = 1:delay
                app.time = app.time + 1; 
                timeLabel = sprintf('Time: %d', app.time);
                app.Time0Label.Text = timeLabel;
                drawnow;
                pause(app.speed);
            end
        end
        
        %Function for letting the nodes move, randomly ads the x and y coordinate
        function moveNodes(app, currentNode, nextNode,bool,srcNode,dstNode)
            for i = 1:app.nodeAmount
                randX = rand()/app.movement;
                randY = rand()/app.movement;
                sign1 = rand();
                sign2 = rand();
                
                if(sign1 > 0.5)
                    app.NodesLocationArray(i, 1) = app.NodesLocationArray(i, 1) + randX;
                else
                    app.NodesLocationArray(i, 1) = app.NodesLocationArray(i, 1) - randX;
                end
                
                if(sign2 > 0.5)
                    app.NodesLocationArray(i, 2) = app.NodesLocationArray(i, 2) + randY;
                else
                    app.NodesLocationArray(i, 2) = app.NodesLocationArray(i, 2) - randY;
                end     
            end
            cla(app.UIAxes);
            drawNetwork(app);
            
            %Also move the source and destination labels
            text(app.UIAxes, app.NodesLocationArray(srcNode,1) - 0.04, app.NodesLocationArray(srcNode,2) - 0.02, "Source",'FontSize', 14) %Added a bit offset of -0.04 and -0.02
            text(app.UIAxes, app.NodesLocationArray(dstNode,1) - 0.06, app.NodesLocationArray(dstNode,2) - 0.02, "Destination",'FontSize', 14) %Added a bit offset of -0.06 and -0.02
            
            checkNodeConnection(app);

            %If data movement then quiver bool == 1 needs to be used if its the flooding part bool == 0 (else) will be used
            if(bool == 1)
                quiver(app.UIAxes, app.NodesLocationArray(currentNode,1), app.NodesLocationArray(currentNode,2), app.NodesLocationArray(nextNode,1)-app.NodesLocationArray(currentNode,1), app.NodesLocationArray(nextNode,2)-app.NodesLocationArray(currentNode,2), 'Color', 'g')       
            end
       end   
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Input stuff that you want to execute at startup
            disp('App has started up!') % Will display on the main MATLAB command window
            xlim(app.UIAxes, [0 1]);
            ylim(app.UIAxes, [0 1]);
        end

        % Button pushed function: CreateNodesButton
        function CreateNodesButtonPushed(app, event)
            app.time = 0;
            app.nodeAmount = app.NumberofNodesEditField.Value; % Number of nodes, input from user
            app.NodesLocationArray = rand(app.nodeAmount,2); % Creates random N x 2 array [2: (X,Y) values]  
            cla(app.UIAxes);
            drawNetwork(app);
        end

        % Button pushed function: StartSimulationButton
        function StartSimulationButtonPushed(app, event)
            app.time = 0;
            checkNodeConnection(app);                                      %function to check if a connection should be made between the nodes (distance and radii between nodes are used)
            StartProtocol(app);
 
        end

        % Button pushed function: AddNodesButton
        function AddNodesButtonPushed(app, event)
            
            nodesAdded = size(app.NodePlacementTextArea.Value, 1);
            %Retrieve the nodes that need to be added
            for i = 1:nodesAdded
                string = app.NodePlacementTextArea.Value{i};
                coordinates = strsplit(string);
                coordinatesx = str2double(coordinates(1));
                coordinatesy = str2double(coordinates(2));
                app.NodesLocationArray(end + 1, 1) = coordinatesx;
                app.NodesLocationArray(end, 2) = coordinatesy;
            end
            
            %Update total amount of nodes and draw the network
            app.NumberofNodesEditField.Value = app.NumberofNodesEditField.Value + nodesAdded;
            app.nodeAmount = app.NumberofNodesEditField.Value;
            
            cla(app.UIAxes);
            drawNetwork(app);
            drawNodeNumbers(app);
            
            %Update the amount that is added
            nodeLabel = sprintf ('Nodes added: %d',nodesAdded);
            app.Nodesadded0Label.Text =  nodeLabel;
            drawnow             
        end

        % Value changed function: RangeSlider
        function RangeSliderValueChanged(app, event)
            value = app.RangeSlider.Value;
            app.nodeRadius = value/100;
            app.RangeField.Value = round(value);
            cla(app.UIAxes);
            drawNetwork(app);
            drawNodeNumbers(app);
        end

        % Button pushed function: ClearSimulationButton
        function ClearSimulationButtonPushed(app, event)
            cla(app.UIAxes) % Clears the whole axes when simulation should stop
            app.time = 0;
            app.nodeAmount = 0;
            app.NodesLocationArray = {[],[]};
            app.brokenCounter = 0;
            
            app.SimulationstatusIdleLabel.Text = 'Simulation status: ';
            app.Time0Label.Text = 'Time: ';
            app.Nodesadded0Label.Text = 'Nodes added: ';                
            app.Connectionbroken0Label.Text = 'Connection broken: 0';
            
            drawnow
        end

        % Button pushed function: NewmessageButton
        function NewmessageButtonPushed(app, event)
            app.time = 0;
            app.brokenCounter = 0;
            cla(app.UIAxes);
            drawNetwork(app);
            checkNodeConnection(app);
            StartProtocol(app);
        end

        % Button pushed function: PauseButton
        function PauseButtonPushed(app, event)
            app.pauseStatus = true;
        end

        % Value changed function: MovementSlider
        function MovementSliderValueChanged(app, event)
            value = app.MovementSlider.Value;
            app.movement = 201 - (value * 2);
            app.MovementField.Value = round(value);
        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
            app.pauseStatus = false;
        end

        % Button pushed function: AddNodeButton
        function AddNodeButtonPushed(app, event)
            %Adjust the current array by increasing the size and adding the new node
            app.NumberofNodesEditField.Value = app.NumberofNodesEditField.Value + 1;
            app.nodeAmount = app.NumberofNodesEditField.Value;
            app.NodesLocationArray(end + 1, 1) = app.XEditField.Value;
            app.NodesLocationArray(end, 2) = app.YEditField.Value;
            
            %Draw the network
            cla(app.UIAxes);
            drawNetwork(app);
            drawNodeNumbers(app);
            
        end

        % Value changed function: SpeedSlider
        function SpeedSliderValueChanged(app, event)
            value = app.SpeedSlider.Value;
            app.speed = 2 - ((value + 1)/100)*2;
            app.SpeedField.Value = round(value);
        end

        % Value changed function: RangeField
        function RangeFieldValueChanged(app, event)
            value = app.RangeField.Value;
            app.nodeRadius = value/100;
            app.RangeSlider.Value = value;
            
            %Draw new network
            cla(app.UIAxes);
            drawNetwork(app);
            drawNodeNumbers(app);
        end

        % Value changed function: MovementField
        function MovementFieldValueChanged(app, event)
            value = app.MovementField.Value;
             app.movement = 201 - (value * 2);
             app.MovementSlider.Value = value;
        end

        % Value changed function: SpeedField
        function SpeedFieldValueChanged(app, event)
            value = app.SpeedField.Value;
            app.speed = 2 - ((value + 1)/100)*2;
            app.SpeedSlider.Value = value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.DSRSimulationUIFigure = uifigure('Visible', 'off');
            app.DSRSimulationUIFigure.Name = 'DSR Simulation';
            app.StartSimulationButton = uibutton(app.DSRSimulationUIFigure, 'push');
            app.StartSimulationButton.ButtonPushedFcn = createCallbackFcn(app, @StartSimulationButtonPushed, true);
            app.StartSimulationButton.Text = 'Start Simulation';
            app.NumberofNodesEditFieldLabel = uilabel(app.DSRSimulationUIFigure);
            app.NumberofNodesEditFieldLabel.Text = 'Number of Nodes';
            app.NumberofNodesEditField = uieditfield(app.DSRSimulationUIFigure, 'numeric');
            app.NumberofNodesEditField.Limits = [0 200];
            app.NumberofNodesEditField.Value = 5;
            app.CreateNodesButton = uibutton(app.DSRSimulationUIFigure, 'push');
            app.CreateNodesButton.ButtonPushedFcn = createCallbackFcn(app, @CreateNodesButtonPushed, true);
            app.CreateNodesButton.Text = 'Create Nodes';
            app.AddNodesButton = uibutton(app.DSRSimulationUIFigure, 'push');
            app.AddNodesButton.ButtonPushedFcn = createCallbackFcn(app, @AddNodesButtonPushed, true);
            app.AddNodesButton.Text = 'Add Nodes';
            app.RangeSliderLabel = uilabel(app.DSRSimulationUIFigure);
            app.RangeSliderLabel.Text = 'Range';
            app.RangeSlider = uislider(app.DSRSimulationUIFigure);
            app.RangeSlider.ValueChangedFcn = createCallbackFcn(app, @RangeSliderValueChanged, true);
            app.RangeSlider.Value = 40;
            app.ClearSimulationButton = uibutton(app.DSRSimulationUIFigure, 'push');
            app.ClearSimulationButton.ButtonPushedFcn = createCallbackFcn(app, @ClearSimulationButtonPushed, true);
            app.ClearSimulationButton.Text = 'Clear Simulation';
            app.SimulationstatusIdleLabel = uilabel(app.DSRSimulationUIFigure);
            app.SimulationstatusIdleLabel.Text = 'Simulation status: Idle';
            app.NewmessageButton = uibutton(app.DSRSimulationUIFigure, 'push');
            app.NewmessageButton.ButtonPushedFcn = createCallbackFcn(app, @NewmessageButtonPushed, true);
            app.NewmessageButton.Text = 'New message';
            app.SourceNodeEditFieldLabel = uilabel(app.DSRSimulationUIFigure);
            app.SourceNodeEditFieldLabel.Text = 'Source Node';
            app.SourceNodeEditField = uieditfield(app.DSRSimulationUIFigure, 'numeric');
            app.SourceNodeEditField.Limits = [0 200];
            app.SourceNodeEditField.Value = 1;
            app.DestinationNodeEditFieldLabel = uilabel(app.DSRSimulationUIFigure);
            app.DestinationNodeEditFieldLabel.Text = 'Destination Node';
            app.DestinationNodeEditField = uieditfield(app.DSRSimulationUIFigure, 'numeric');
            app.DestinationNodeEditField.Limits = [0 200];
            app.DestinationNodeEditField.Value = 2;
            app.Time0Label = uilabel(app.DSRSimulationUIFigure);
            app.Time0Label.Text = 'Time: 0';
            app.MovementSliderLabel = uilabel(app.DSRSimulationUIFigure);
            app.MovementSliderLabel.Text = 'Movement';
            app.MovementSlider = uislider(app.DSRSimulationUIFigure);
            app.MovementSlider.ValueChangedFcn = createCallbackFcn(app, @MovementSliderValueChanged, true);
            app.MovementSlider.Value = 50;
            app.PauseButton = uibutton(app.DSRSimulationUIFigure, 'push');
            app.PauseButton.ButtonPushedFcn = createCallbackFcn(app, @PauseButtonPushed, true);
            app.PauseButton.Text = 'Pause';
            app.PlayButton = uibutton(app.DSRSimulationUIFigure, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Text = 'Play';
            app.XEditFieldLabel = uilabel(app.DSRSimulationUIFigure);
            app.XEditFieldLabel.Text = 'X:';
            app.XEditField = uieditfield(app.DSRSimulationUIFigure, 'numeric');
            app.XEditField.Limits = [0 200];
            app.XEditField.Value = 1;
            app.YEditFieldLabel = uilabel(app.DSRSimulationUIFigure);
            app.YEditFieldLabel.Text = 'Y:';
            app.YEditField = uieditfield(app.DSRSimulationUIFigure, 'numeric');
            app.YEditField.Limits = [0 200];
            app.YEditField.Value = 1;
            app.AddNodeButton = uibutton(app.DSRSimulationUIFigure, 'push');
            app.AddNodeButton.ButtonPushedFcn = createCallbackFcn(app, @AddNodeButtonPushed, true);
            app.AddNodeButton.Text = 'Add Node';
            app.NodePlacementTextAreaLabel = uilabel(app.DSRSimulationUIFigure);
            app.NodePlacementTextAreaLabel.Text = 'Node Placement';
            app.NodePlacementTextArea = uitextarea(app.DSRSimulationUIFigure);
            app.Nodesadded0Label = uilabel(app.DSRSimulationUIFigure);
            app.Nodesadded0Label.Text = 'Nodes added: 0';
            app.SpeedSliderLabel = uilabel(app.DSRSimulationUIFigure);
            app.SpeedSliderLabel.Text = 'Speed';
            app.SpeedSlider = uislider(app.DSRSimulationUIFigure);
            app.SpeedSlider.MajorTicks = [0 10 20 30 40 50 60 70 80 90 100];
            app.SpeedSlider.ValueChangedFcn = createCallbackFcn(app, @SpeedSliderValueChanged, true);
            app.SpeedSlider.Value = 50;
            app.RangeField = uieditfield(app.DSRSimulationUIFigure, 'numeric');
            app.RangeField.Limits = [0 200];
            app.RangeField.ValueChangedFcn = createCallbackFcn(app, @RangeFieldValueChanged, true);
            app.RangeField.Value = 40;
            app.MovementField = uieditfield(app.DSRSimulationUIFigure, 'numeric');
            app.MovementField.Limits = [0 200];
            app.MovementField.ValueChangedFcn = createCallbackFcn(app, @MovementFieldValueChanged, true);
            app.MovementField.Value = 50;
            app.SpeedField = uieditfield(app.DSRSimulationUIFigure, 'numeric');
            app.SpeedField.Limits = [0 200];
            app.SpeedField.ValueChangedFcn = createCallbackFcn(app, @SpeedFieldValueChanged, true);
            app.SpeedField.Value = 50;
            app.Connectionbroken0Label = uilabel(app.DSRSimulationUIFigure);
            app.Connectionbroken0Label.Text = 'Connection broken: 0';
            app.UIAxes = uiaxes(app.DSRSimulationUIFigure);
            title(app.UIAxes, {'Routing protocol '; 'simulation'})
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            % Show the figure after all components are created
            app.DSRSimulationUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Dynamic_Source_Routing_5

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DSRSimulationUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DSRSimulationUIFigure)
        end
    end
end