classdef base_model_routing2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        UIAxes                       matlab.ui.control.UIAxes
        StartSimulationButton        matlab.ui.control.Button
        NumberofNodesEditFieldLabel  matlab.ui.control.Label
        NumberofNodesEditField       matlab.ui.control.NumericEditField
        CreateNodesButton            matlab.ui.control.Button
        ExecFunctionCommandWindowButton  matlab.ui.control.Button
    end

    
    % Global variables, call them by prepending 'app.' in front of them
    properties (Access = private)
        NodesLocationArray % Description
        SimulationSpeed = 0.05 % Speed at which the signal moves in terms of seconds
        
    end
    
    methods (Access = private)
        
        % This is a custom function that you can use
        function customFunction(app, par1)
            disp(['The simulation speed is set at: ', num2str(app.SimulationSpeed), ' seconds per frame'])
            
            % Variables are not stored in the workspace by default (can use debug mode to show it)
            % Or use the function assignin (should declare it locally first before assignin works)            
            if (par1 == true)
                simspeed = app.SimulationSpeed;            
                assignin('base', 'savedVar_simspeed', simspeed);
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Input stuff that you want to execute at startup
            disp('App has started up!') % Will display on the main MATLAB command window
            xlim(app.UIAxes, [0 1]);
            ylim(app.UIAxes, [0 1]);
        end

        % Button pushed function: CreateNodesButton
        function CreateNodesButtonPushed(app, event)
            cla(app.UIAxes) % Clears the whole axes when new nodes are created
            N = app.NumberofNodesEditField.Value; % Number of nodes, input from user
            
            app.NodesLocationArray = rand(N,2); % Creates random N x 2 array [2: (X,Y) values]
            
            % Plot nodes as small circles
            plot(app.UIAxes, app.NodesLocationArray(:,1), app.NodesLocationArray(:,2), 'ok', 'MarkerfaceColor', 'k');
        end

        % Button pushed function: StartSimulationButton
        function StartSimulationButtonPushed(app, event)
            hold(app.UIAxes, 'on') % Ensures all previous plots (nodes) will remain on the axes
            
            nmbrNodes=size(app.NodesLocationArray, 1);
            A=zeros([nmbrNodes, nmbrNodes]);
            linkProbability = 0.2;
            cache = {};
            for i = 1:nmbrNodes
                for j = (i+1):nmbrNodes
                    rndNmbr = rand(1);
                    if rndNmbr < linkProbability
                        A(i,j) = 1;
                        A(j,i) = 1;
                        line(app.UIAxes, [app.NodesLocationArray(i,1) app.NodesLocationArray(j,1)], [app.NodesLocationArray(i,2) app.NodesLocationArray(j,2)], 'Color', 'k'); 
                    end
                end                
            end
            srcNode=1;
            text(app.UIAxes, app.NodesLocationArray(srcNode,1), app.NodesLocationArray(srcNode,2), "Source")
            dstNode=randi([2, nmbrNodes])
            text(app.UIAxes, app.NodesLocationArray(dstNode,1), app.NodesLocationArray(dstNode,2), "Destination")
            alreadyFlood = zeros([nmbrNodes,1])
            flood = zeros([nmbrNodes,1])
            flood(srcNode) = 1;
            floodNext = zeros([nmbrNodes,1]);
            dstNodeReached=0;
            
            cache = {}
            cache{srcNode,1} = srcNode
            hopnumber = 0;
            while sum(flood)>0
                hopnumber=hopnumber+1;
                nmbrFloods=0;
                for i=1:nmbrNodes
                    if flood(i)==1
                        for j=1:nmbrNodes
                            if A(i,j)==1
                               nmbrFloods = nmbrFloods + 1
                               quiver(app.UIAxes, app.NodesLocationArray(i,1), app.NodesLocationArray(i,2), app.NodesLocationArray(j,1)-app.NodesLocationArray(i,1), app.NodesLocationArray(j,2)-app.NodesLocationArray(i,2), 'Color', 'r');
                               for ix=1:size(cache(i,:),2)
                                   cache(j,ix)=cache(i, ix);
                               end    
                               cache{j,hopnumber+1}=j;
                               
                               if j==dstNode                                                                
                                    dstNodeReached=1;
                               elseif alreadyFlood(j)==0
                                    floodNext(j)=1;  
                               end    
            
                            end                           
                        end
                        alreadyFlood(i)=1;
                        flood(i)=0;
                    end
                end
                pause(5)
               children = get(app.UIAxes, 'children');
               if nmbrFloods>0                    
                    delete(children(1:nmbrFloods+1));
               end

                for idx=1:nmbrNodes
                    if floodNext(idx)==1
                        flood(idx)=1;
                    end
                end
    
            end 
            nmbrNodes
            if dstNodeReached==1             
                for i=size(cache(dstNode),2):-1:2
                    currentNode = cache(dstNode, i);
                    nextNode = cache(dstNode, i-1);
                    quiver(app.UIAxes, app.NodesLocationArray(currentNode,1), app.NodesLocationArray(currentNode,2), app.NodesLocationArray(nextNode,1)-app.NodesLocationArray(currentNode,1), app.NodesLocationArray(nextNode,2)-app.NodesLocationArray(currentNode,2), 'Color', 'r')
                    pauze(1)
                end
            end
            
        end

        % Button pushed function: ExecFunctionCommandWindowButton
        function ExecFunctionCommandWindowButtonPushed(app, event)
            customFunction(app, true); % Executes our custom function
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, {'Routing protocol '; 'simulation'})
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.StartSimulationButton = uibutton(app.UIFigure, 'push');
            app.StartSimulationButton.ButtonPushedFcn = createCallbackFcn(app, @StartSimulationButtonPushed, true);
            app.StartSimulationButton.Text = 'Start Simulation';
            app.NumberofNodesEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofNodesEditFieldLabel.Text = 'Number of Nodes';
            app.NumberofNodesEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumberofNodesEditField.Value = 10;
            app.CreateNodesButton = uibutton(app.UIFigure, 'push');
            app.CreateNodesButton.ButtonPushedFcn = createCallbackFcn(app, @CreateNodesButtonPushed, true);
            app.CreateNodesButton.Text = 'Create Nodes';
            app.ExecFunctionCommandWindowButton = uibutton(app.UIFigure, 'push');
            app.ExecFunctionCommandWindowButton.ButtonPushedFcn = createCallbackFcn(app, @ExecFunctionCommandWindowButtonPushed, true);
            app.ExecFunctionCommandWindowButton.Text = {'Exec Function'; 'Command'; 'Window'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = base_model_routing2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end